classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RmsOverlap                      matlab.ui.control.Slider
        Slider_2Label_3                 matlab.ui.control.Label
        Slider_2Label_2                 matlab.ui.control.Label
        RmsWindow                       matlab.ui.control.Slider
        RMSparametersLabel              matlab.ui.control.Label
        OutlierRemovalLabel             matlab.ui.control.Label
        OrderSlider                     matlab.ui.control.Slider
        OrderLabel                      matlab.ui.control.Label
        AutomaticfilterparametersLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FrequencyAnalysisLabel          matlab.ui.control.Label
        ProcessingTypeLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MovingRMSButton                 matlab.ui.control.RadioButton
        BandpassfilterrectificationButton  matlab.ui.control.RadioButton
        Tree2                           matlab.ui.container.Tree
        MTrapDescendensLNode_2          matlab.ui.container.TreeNode
        MTrapDescendensRNode_2          matlab.ui.container.TreeNode
        MDeltodeusAnteriorLNode_2       matlab.ui.container.TreeNode
        MDeltodeusAnteriorRNode_2       matlab.ui.container.TreeNode
        MExtCarpiRadialisdomNode_2      matlab.ui.container.TreeNode
        lampCH5                         matlab.ui.control.Lamp
        lampCH4                         matlab.ui.control.Lamp
        lampCH3                         matlab.ui.control.Lamp
        lampCH2                         matlab.ui.control.Lamp
        lampCH1                         matlab.ui.control.Lamp
        Image_2                         matlab.ui.control.Image
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        DataManagementLabel             matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        MainPlot                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        InfoButton                      matlab.ui.control.Button
        ApplySavitzkyGolayCheckBox      matlab.ui.control.CheckBox
        AddtoexportCheckBox             matlab.ui.control.CheckBox
        ShowongraphCheckBox             matlab.ui.control.CheckBox
        MVCLabel                        matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        BandPassFilterLabel             matlab.ui.control.Label
        FilterPlot                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        baseFileExists = 0;
        RAWch

        dataCh1
        dataCh2
        dataCh3
        dataCh4
        dataCh5

        mvcCh1
        mvcCh2
        mvcCh3
        mvcCh4
        mvcCh5

        RAWmvcCh1
        RAWmvcCh2
        RAWmvcCh3
        RAWmvcCh4
        RAWmvcCh5

        mvcREAD = [0, 0, 0, 0, 0]

        track = false;
        filter = 1;
        filter_order = 1
        filter_low = 0.2;
        filter_high = 0.5;
        outlier = 1;
        outlier_order = 1;

        rms_window = 4;
        rms_overlap = 0;


    end
    
    methods (Access = private)
        
        function PlotRms(app, window)
           movrmsWin = dsp.MovingRMS(window);
            y1 = movrmsWin(app.rawdata);
            plot(app.UIAxes_2, y1(20:end)) 
        end
        
        function reCalculateData(app)
            disp("RECALCULATING")
            if (app.mvcREAD(1) ~= 0)
                output = dataProcessing(app.RAWmvcCh1, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                app.mvcCh1 = output(1);
            end
            if (app.mvcREAD(2) ~= 0)
                output = dataProcessing(app.RAWmvcCh2, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                app.mvcCh2 = output(2);
            end
            if (app.mvcREAD(3) ~= 0)
            output = dataProcessing(app.RAWmvcCh3, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
            app.mvcCh3 = output(3);
            end
            if (app.mvcREAD(4) ~= 0)
            output = dataProcessing(app.RAWmvcCh4, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
            app.mvcCh4 = output(4);
            end
            if (app.mvcREAD(5) ~= 0)
            output = dataProcessing(app.RAWmvcCh5, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
            app.mvcCh5 = output(5);
            end
            
            if (app.baseFileExists ~= 0)
                disp("Data recalculated")
                output = dataProcessing(app.RAWch, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low, app.filter_high);

                if (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (L)")
                    if (app.mvcREAD(1) ~= 0)
                        fftData = ProcessMVC(output(:,1), app.mvcCh1);
                        N = size(fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(fftData(N/2:N, 1)))

                        phase = atan2(imag(fftData(N/2:N,1)), real(fftData(N/2:N,1)) ).*180/pi; %phase information
                        phase2 = angle(fftData(N/2:N,1)).*180/pi;
                        disp(fftData)
                        plot(app.PhasePlot, f, phase2);


                        disp(" Not Empty")
                    end
    
            
                elseif (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (R)")
                    if (app.mvcREAD(2) ~= 0)
                        plot(app.MainPlot, ProcessMVC(output(:,2), app.mvcCh2)) 
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                    if (app.mvcREAD(3) ~= 0)
                        plot(app.MainPlot, ProcessMVC(output(:,3), app.mvcCh3)) 
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                    if (app.mvcREAD(4) ~= 0)
                        plot(app.MainPlot, ProcessMVC(output(:,4), app.mvcCh4)) 
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                    if (app.mvcREAD(5) ~= 0)
                        plot(app.MainPlot, ProcessMVC(output(:,5), app.mvcCh5)) 
                        disp(" Not Empty")
                    end

                end
            else
                uiconfirm(app.UIFigure,'No base channel has been added! Pleas push the "Load data" button.','Error!',...
                                    'Icon','info');
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
                disp(fullfile(path,file))
                [app.RAWch, ~, ~] = FileParser(fullfile(path,file));

                output = dataProcessing(app.RAWch, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low, app.filter_high);
                app.dataCh1 = ProcessMVC(output(1),app.mvcCh1);
                app.dataCh2 = ProcessMVC(output(2),app.mvcCh2);
                app.dataCh3 = ProcessMVC(output(3),app.mvcCh3);
                app.dataCh4 = ProcessMVC(output(4),app.mvcCh4);
                app.dataCh5 = ProcessMVC(output(5),app.mvcCh5);

                app.baseFileExists = 1;

                uiconfirm(app.UIFigure,'File has been loaded succesfully! Please select a channel.','File loaded',...
                                    'Icon','info');
                app.reCalculateData();
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            uigetdir(matlabroot,'Choose folder to save the analysis');
            app.Label_2.Visible = 'on';
        end

        % Callback function
        function LoadMVCButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.lampCH1.Color = [0 1 0];
            end   
        end

        % Callback function
        function CleardataButtonPushed(app, event)

            selection = uiconfirm(app.UIFigure,'Clear data?','Confirm Clearing',...
                                    'Icon','warning');
            % Handle response
            switch selection
                case 'OK'
                    disp('Clearing...')
                    app.lampCH1.Color = [1 0 0];
                    app.Lamp.Color = [1 0 0];
                case 'Cancel'
                    disp('...')
            end
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)

        end

        % Value changed function: RmsWindow
        function RmsWindowValueChanged(app, event)
            value = app.RmsWindow.Value;
            app.rms_window = round(value);
            app.RmsOverlap.Limits = [0, app.rms_window-1];

            
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            if (selectedNodes.Text == "M. Trap. Descendens (L)")
                if (app.mvcCh1 ~= 0)
                    app.reCalculateData();
                else
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (L)!','MVC M. Trap. Descendens (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh1, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh1, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                                app.mvcCh1 = output(1);
                                app.lampCH1.Color = [0 1 0];
                                app.mvcREAD(1) = 1;
                                app.reCalculateData();

                            end
                    end
                end

        
            elseif (selectedNodes.Text == "M. Trap. Descendens (R)")
                if (app.mvcCh2 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (R)!','MVC M. Trap. Descendens (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh2, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh2, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                                app.mvcCh2 = output(2);
                                app.lampCH2.Color = [0 1 0];
                                app.mvcREAD(2) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                if (app.mvcCh3 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (L)!','MVC M. Deltoïdeus Anterior (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh3, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh3, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                                app.mvcCh3 = output(3);
                                app.lampCH3.Color = [0 1 0];
                                app.mvcREAD(3) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                if (app.mvcCh4 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (R)!','MVC M. Deltoïdeus Anterior (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh4, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh4, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                                app.mvcCh4 = output(4);
                                app.lampCH4.Color = [0 1 0];
                                app.mvcREAD(4) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                if (app.mvcCh1 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Ext. Carpi Radialis (dom.)!','MVC M. Ext. Carpi Radialis (dom.)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh5, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh5, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high);
                                app.mvcCh5 = output(5);
                                app.lampCH5.Color = [0 1 0];
                                app.mvcREAD(5) = 1;
                                app.reCalculateData();
                            end
                    end
                end
            else
                disp("idk")
            end
        end

        % Value changed function: OrderSlider
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            app.OrderSlider.Value = round(value);
            app.outlier_order = round(value);
            app.reCalculateData();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton.Text == "Moving RMS")
                app.track = true;
                app.RmsOverlap.Visible = true;
                app.RmsWindow.Visible = true;
                app.RmsOverlap.RMSparametersLabel = true;
            else
                app.track = false;
            end
            %app.reCalculateData();
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if (value == "STD")
                app.outlier = 1;
            elseif (value == "IQR")
                app.outlier = 2;
            elseif (value == "GESD")
                app.outlier = 3;
            elseif (value == "Z-Score")
                app.outlier = 4;
            end 
            app.reCalculateData();
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged2(app, event)
            value = app.FilterDropDown.Value;
            if (value == "Chebyshev 1")
               
            elseif (value == "Chebyshev 2")
               
            elseif (value == "ButtWorth")
               
            elseif (value == "Bessel")

            end 


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {774, 774, 774};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {774, 774};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x', 225};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataManagementLabel = uilabel(app.LeftPanel);
            app.DataManagementLabel.Text = 'Data Management';
            app.LoaddataButton = uibutton(app.LeftPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportdataButton = uibutton(app.LeftPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'STD', 'IQR', 'GESD', 'Z-Score'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'STD';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'logo.png';
            app.lampCH1 = uilamp(app.LeftPanel);
            app.lampCH2 = uilamp(app.LeftPanel);
            app.lampCH3 = uilamp(app.LeftPanel);
            app.lampCH4 = uilamp(app.LeftPanel);
            app.lampCH5 = uilamp(app.LeftPanel);
            app.Tree2 = uitree(app.LeftPanel);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.MTrapDescendensLNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensLNode_2.Text = 'M. Trap. Descendens (L)';
            app.MTrapDescendensRNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensRNode_2.Text = 'M. Trap. Descendens (R)';
            app.MDeltodeusAnteriorLNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorLNode_2.Text = 'M. Deltoïdeus Anterior (L)';
            app.MDeltodeusAnteriorRNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorRNode_2.Text = 'M. Deltoïdeus Anterior (R)';
            app.MExtCarpiRadialisdomNode_2 = uitreenode(app.Tree2);
            app.MExtCarpiRadialisdomNode_2.Text = 'M. Ext. Carpi Radialis (dom.)';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BandpassfilterrectificationButton = uiradiobutton(app.ButtonGroup);
            app.BandpassfilterrectificationButton.Text = 'Band pass filter + rectification';
            app.BandpassfilterrectificationButton.Value = true;
            app.MovingRMSButton = uiradiobutton(app.ButtonGroup);
            app.MovingRMSButton.Text = 'Moving RMS';
            app.ProcessingTypeLabel = uilabel(app.LeftPanel);
            app.ProcessingTypeLabel.Text = 'Processing Type';
            app.FrequencyAnalysisLabel = uilabel(app.LeftPanel);
            app.FrequencyAnalysisLabel.Text = 'Frequency Analysis';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Value = 'On';
            app.AutomaticfilterparametersLabel = uilabel(app.LeftPanel);
            app.AutomaticfilterparametersLabel.Text = {'Automatic '; 'filter parameters'};
            app.OrderLabel = uilabel(app.LeftPanel);
            app.OrderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.LeftPanel);
            app.OrderSlider.Limits = [1 3];
            app.OrderSlider.MajorTicks = [1 2 3];
            app.OrderSlider.ValueChangedFcn = createCallbackFcn(app, @OrderSliderValueChanged, true);
            app.OrderSlider.MinorTicks = [1 2 3];
            app.OrderSlider.Value = 1;
            app.OutlierRemovalLabel = uilabel(app.LeftPanel);
            app.OutlierRemovalLabel.Text = 'Outlier Removal';
            app.RMSparametersLabel = uilabel(app.LeftPanel);
            app.RMSparametersLabel.Visible = 'off';
            app.RMSparametersLabel.Text = 'RMS parameters';
            app.RmsWindow = uislider(app.LeftPanel);
            app.RmsWindow.Limits = [2 10];
            app.RmsWindow.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RmsWindow.ValueChangedFcn = createCallbackFcn(app, @RmsWindowValueChanged, true);
            app.RmsWindow.MinorTicks = [];
            app.RmsWindow.Visible = 'off';
            app.RmsWindow.Value = 4;
            app.Slider_2Label_2 = uilabel(app.LeftPanel);
            app.Slider_2Label_2.Visible = 'off';
            app.Slider_2Label_2.Text = 'Window';
            app.Slider_2Label_3 = uilabel(app.LeftPanel);
            app.Slider_2Label_3.Visible = 'off';
            app.Slider_2Label_3.Text = 'Overlap';
            app.RmsOverlap = uislider(app.LeftPanel);
            app.RmsOverlap.Limits = [0 3];
            app.RmsOverlap.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.RmsOverlap.MinorTicks = [];
            app.RmsOverlap.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainPlot = uiaxes(app.CenterPanel);
            title(app.MainPlot, 'Rectification with Band-pass')
            xlabel(app.MainPlot, 'f (Hz)')
            ylabel(app.MainPlot, '|X(k)|')
            zlabel(app.MainPlot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterPlot = uiaxes(app.RightPanel);
            zlabel(app.FilterPlot, 'Z')
            app.BandPassFilterLabel = uilabel(app.RightPanel);
            app.BandPassFilterLabel.Text = 'Band Pass Filter';
            app.FilterDropDown = uidropdown(app.RightPanel);
            app.FilterDropDown.Items = {'Chebyshev 1', 'Chebyshev 2', 'ButtWorth', 'Bessel', 'Elliptical'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged2, true);
            app.FilterDropDown.Value = 'Chebyshev 1';
            app.CutoffEditFieldLabel = uilabel(app.RightPanel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.RightPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'numeric');
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.Text = 'Default';
            app.MVCLabel = uilabel(app.RightPanel);
            app.MVCLabel.Text = 'MVC';
            app.ShowongraphCheckBox = uicheckbox(app.RightPanel);
            app.ShowongraphCheckBox.Text = 'Show on graph';
            app.AddtoexportCheckBox = uicheckbox(app.RightPanel);
            app.AddtoexportCheckBox.Text = 'Add to export';
            app.ApplySavitzkyGolayCheckBox = uicheckbox(app.RightPanel);
            app.ApplySavitzkyGolayCheckBox.Text = 'Apply Savitzky-Golay';
            app.InfoButton = uibutton(app.RightPanel, 'push');
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end